classdef GuiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadCollisionObjectSwitch       matlab.ui.control.Switch
        LoadCollisionObjectSwitchLabel  matlab.ui.control.Label
        CollisionDetectedLamp           matlab.ui.control.Lamp
        CollisionDetectedLampLabel      matlab.ui.control.Label
        DoorClosedLamp                  matlab.ui.control.Lamp
        DoorClosedLampLabel             matlab.ui.control.Label
        SystemStatusEditField           matlab.ui.control.EditField
        SystemStatusEditFieldLabel      matlab.ui.control.Label
        DoorSlider                      matlab.ui.control.Slider
        DoorSliderLabel                 matlab.ui.control.Label
        EResetButton                    matlab.ui.control.StateButton
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        EStopButton                     matlab.ui.control.StateButton
        LoadNewPassportsButton          matlab.ui.control.StateButton
        SystemSwitch                    matlab.ui.control.Switch
        SystemSwitchLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadNewPassportsButton
        function LoadNewPassportsButtonValueChanged(app, event)
            value = app.LoadNewPassportsButton.Value;
            if value == 0
                app.LoadNewPassportsButton.FontWeight = 'bold';
                if strcmp(app.SystemSwitch.Value,'Off')
                    app.SystemStatusEditField.Value = 'System is OFF, Cannot Load Passport';
                else
                    app.SystemStatusEditField.Value = 'Passport Stamping Initiated';
                end
            else
                app.LoadNewPassportsButton.FontWeight = 'normal';
                app.SystemStatusEditField.Value = 'Passport Stamping Stopped';
            end

        end

        % Value changed function: SystemSwitch
        function SystemSwitchValueChanged(app, event)
            value = app.SystemSwitch.Value;
            if strcmp(value,'Off')
            app.Lamp.Color = [0.8,0.8,0.8];
            app.SystemStatusEditField.Value = 'System Turning OFF';
            else
                app.Lamp.Color = [0,1,0];
                app.SystemStatusEditField.Value = 'System Turning ON';
            end
        end

        % Value changed function: EStopButton
        function EStopButtonValueChanged(app, event)
            value = app.EStopButton.Value;
            if value == 0
                app.EStopButton.FontWeight = 'bold';
                app.Lamp_2.Color = [1,0,0];
                app.SystemStatusEditField.Value = 'Emergency Mode Activated All Robots Halt';
            else
                app.EStopButton.FontWeight = 'normal';
                app.SystemStatusEditField.Value = 'Press E-Reset to Continue Operation';
            end
        end

        % Value changed function: EResetButton
        function EResetButtonValueChanged(app, event)
            value = app.EResetButton.Value;
            if value == 0
                app.EResetButton.FontWeight = 'bold';
                
                if app.EStopButton.Value == 1
                app.Lamp_2.Color = [0.8,0.8,0.8];
                app.SystemStatusEditField.Value = 'Continuing Operation';
                else
                    app.SystemStatusEditField.Value = 'E-Stop is Still ON';
                end
            else
                app.EResetButton.FontWeight = 'normal';
            end            
        end

        % Value changed function: DoorSlider
        function DoorSliderValueChanged(app, event)
            value = app.DoorSlider.Value;
            if value > 10
                app.DoorClosedLamp.Color = [1,0,0];
                app.SystemStatusEditField.Value = 'Door is OPEN, Robots to Default Position and Wait';
                app.DoorClosedLampLabel.Text = 'Door Open';
            else
                app.DoorClosedLamp.Color = [0,1,0];
                app.SystemStatusEditField.Value = 'Door is Closed, Robots Continue Operation';
                app.DoorClosedLampLabel.Text = 'Door Close';
            end
        end

        % Value changed function: LoadCollisionObjectSwitch
        function LoadCollisionObjectSwitchValueChanged(app, event)
            value = app.LoadCollisionObjectSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SystemSwitchLabel = uilabel(app.UIFigure);
            app.SystemSwitchLabel.Text = 'System Switch';
            app.SystemSwitch = uiswitch(app.UIFigure, 'slider');
            app.SystemSwitch.ValueChangedFcn = createCallbackFcn(app, @SystemSwitchValueChanged, true);
            app.LoadNewPassportsButton = uibutton(app.UIFigure, 'state');
            app.LoadNewPassportsButton.ValueChangedFcn = createCallbackFcn(app, @LoadNewPassportsButtonValueChanged, true);
            app.LoadNewPassportsButton.Text = 'Load New Passports';
            app.LoadNewPassportsButton.Value = true;
            app.EStopButton = uibutton(app.UIFigure, 'state');
            app.EStopButton.ValueChangedFcn = createCallbackFcn(app, @EStopButtonValueChanged, true);
            app.EStopButton.Text = 'E-Stop';
            app.EStopButton.Value = true;
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.EResetButton = uibutton(app.UIFigure, 'state');
            app.EResetButton.ValueChangedFcn = createCallbackFcn(app, @EResetButtonValueChanged, true);
            app.EResetButton.Text = 'E-Reset';
            app.EResetButton.Value = true;
            app.DoorSliderLabel = uilabel(app.UIFigure);
            app.DoorSliderLabel.Text = 'Door Slider';
            app.DoorSlider = uislider(app.UIFigure);
            app.DoorSlider.ValueChangedFcn = createCallbackFcn(app, @DoorSliderValueChanged, true);
            app.SystemStatusEditFieldLabel = uilabel(app.UIFigure);
            app.SystemStatusEditFieldLabel.Text = 'System Status';
            app.SystemStatusEditField = uieditfield(app.UIFigure, 'text');
            app.DoorClosedLampLabel = uilabel(app.UIFigure);
            app.DoorClosedLampLabel.Text = 'Door Closed';
            app.DoorClosedLamp = uilamp(app.UIFigure);
            app.CollisionDetectedLampLabel = uilabel(app.UIFigure);
            app.CollisionDetectedLampLabel.Text = 'Collision Detected';
            app.CollisionDetectedLamp = uilamp(app.UIFigure);
            app.LoadCollisionObjectSwitchLabel = uilabel(app.UIFigure);
            app.LoadCollisionObjectSwitchLabel.Text = 'Load Collision Object';
            app.LoadCollisionObjectSwitch = uiswitch(app.UIFigure, 'slider');
            app.LoadCollisionObjectSwitch.ValueChangedFcn = createCallbackFcn(app, @LoadCollisionObjectSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end